classdef ImageEditingAppFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        OriginalImageLabel     matlab.ui.control.Label
        Image                  matlab.ui.control.UIAxes
        EditedImageLabel       matlab.ui.control.Label
        Image2                 matlab.ui.control.UIAxes
        RotateImage1           matlab.ui.control.Button
        RotateImage2           matlab.ui.control.Button
        LoadImageButton        matlab.ui.control.Button
        ResetButton            matlab.ui.control.Button
        BlackWhiteButton       matlab.ui.control.Button
        EdgeDetectionButton    matlab.ui.control.Button
        ContrastSliderLabel    matlab.ui.control.Label
        ContrastSlider         matlab.ui.control.Slider
        BrightnessSlider       matlab.ui.control.Slider
        BrightnessSliderLabel  matlab.ui.control.Label
        RedAxes                matlab.ui.control.UIAxes
        GreenAxes              matlab.ui.control.UIAxes
        BlueAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        FileName;
        FilePath;
        ImageArray1=[]; % array for original images
        ImageArray2=[]; % array for edited images
        Index1;
        Index2;
        bwFlag=false; %Black & White Flag
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
             im = imagefile;
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Gray Image
                    imagesc(app.Image2,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % RGB Image
                    imagesc(app.Image2,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    

            end
                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            %get the file path and name %             
            [name,path]=uigetfile({'*.*;*.jpg;*.png;*.jpeg'}, 'Select file');
            fullname = [path,name];
            I = imread(fullname);
            
            % Rotate the Image %
            J = imrotate(I,-90,'bilinear','loose');
            
            % Show The Selected Image In The Main Window %
            imshow(J, 'Parent', app.Image);  
            imshow(J, 'Parent', app.Image2); 
            
            % Save The File Details In The Global Variables %
            app.FileName=name;
            app.FilePath=path;
            app.Index1=1;
            app.Index2=1;
            
            % Save The Selected Image In The Image Array %
            app.ImageArray1{app.Index1}=J;
            app.ImageArray2{app.Index2}=J;
            rotate3d(app.Image,'on');
            
            % Update The Image RGB Histogram %
            updateimage(app,J);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           % Load The Original Image %
            app.ImageArray1{app.Index1}=app.ImageArray1{1};
            app.ImageArray2{app.Index2}=app.ImageArray2{1};
            app.bwFlag=false;
            
            % Show The Image In The Main Window %
            imshow(app.ImageArray1{app.Index1}, 'Parent', app.Image);  
            imshow(app.ImageArray2{app.Index2}, 'Parent', app.Image2); 
            
            % Update The Image Histogram %
            updateimage(app,app.ImageArray1{app.Index1});
        end

        % Button pushed function: BlackWhiteButton
        function BlackWhiteButtonPushed(app, event)
          % Check If The Color Is Already Black&White %
            if app.bwFlag==false   %If Not             
                gray=rgb2gray(app.ImageArray2{app.Index2}); %COnvert to GrayScale
                app.Index2=app.Index2+1;  
                app.ImageArray2{app.Index2}=gray;
                imshow(gray, 'Parent', app.Image2); % Show The Image In The Main Window
                updateimage(app,gray); % Update The imgae Histogram
                app.bwFlag=true; % Set The B&W Flag To Be True               
               else
                imshow(app.ImageArray2{app.Index2},'Parent', app.Image2);  
                updateimage(app,app.ImageArray2{app.Index2});                 
            end
        end

        % Button pushed function: EdgeDetectionButton
        function EdgeDetectionButtonPushed(app, event)
           % Edge Detection - Works Only When Loading An RGB Image %
            I=app.ImageArray2{app.Index2}; 
            gray = rgb2gray(I); % Convert To GrayScale
            BW = edge(gray,'canny'); % Perform The Edge Detection Using The 'Canny' Method
            app.ImageArray2{app.Index2}=BW;
            updateimage(app,app.ImageArray2{app.Index2}); % Update The Image Histogram
            imshow(BW, 'Parent', app.Image2); % Show The Image In The Main WIndow          
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            Contrastvalue = app.ContrastSlider.Value;
            I=app.ImageArray2{app.Index2};                        
            brightnessFactor = app.BrightnessSlider.Value; % Get The Brightness Value                        
            contrastFactor = Contrastvalue; % Get The Contrast Value                        
            newImage = uint8(double(I) * contrastFactor + brightnessFactor); % Manipulate The Image Using The Contrast And Brightness             
            imshow(newImage,'Parent',app.Image2); % Show The Image In The Main Window                       
            updateimage(app,app.ImageArray2{app.Index2}); % Update The Image Histogram 
            app.ImageArray2{app.Index2}=newImage;        
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            BrightnessValue = app.BrightnessSlider.Value;
            I=app.ImageArray2{app.Index2};
            I1=I+BrightnessValue; % Manipulate The Image Using The Brightness Value
            imshow(I1, 'Parent', app.Image2); % Show The Image In The Main Window
            app.ImageArray2{app.Index2}=I1;
            updateimage(app,app.ImageArray2{app.Index2}); % Update The Image Histogram 
        end

        % Button pushed function: RotateImage1
        function RotateImage1ButtonPushed(app, event)
            I=app.ImageArray1{app.Index1};
            J = imrotate(I,-90,'bilinear','crop');
            app.ImageArray1{app.Index1+1}=J;
            app.Index1=app.Index1+1;
            app.Index2=app.Index2+1;
            app.ImageArray2{app.Index2}=J;
            app.bwFlag=false;
            imshow(J, 'Parent', app.Image);  
        end

        % Button pushed function: RotateImage2
        function RotateImage2ButtonPushed(app, event)
            I=app.ImageArray2{app.Index2};
            J = imrotate(I,-90,'bilinear','crop');
            app.ImageArray2{app.Index2+1}=J;
            app.Index2=app.Index2+1;
            imshow(J, 'Parent', app.Image2);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.Image = uiaxes(app.UIFigure);
            title(app.Image, '')
            xlabel(app.Image, '')
            ylabel(app.Image, '')
            app.Image.XTick = [0 0.5 1];
            app.Image.XTickLabel = {'0'; '128'; '255'};
            app.Image.YTickLabel = {''; ''; ''};
            app.EditedImageLabel = uilabel(app.UIFigure);
            app.EditedImageLabel.Text = 'Edited Image';
            app.Image2 = uiaxes(app.UIFigure);
            title(app.Image2, '')
            xlabel(app.Image2, '')
            ylabel(app.Image2, '')
            app.Image2.XTick = [0 0.5 1];
            app.Image2.XTickLabel = {'0'; '128'; '255'};
            app.Image2.YTickLabel = {''; ''; ''};
            app.RotateImage1 = uibutton(app.UIFigure, 'push');
            app.RotateImage1.ButtonPushedFcn = createCallbackFcn(app, @RotateImage1ButtonPushed, true);
            app.RotateImage1.Icon = 'RotateIcon.png';
            app.RotateImage1.Text = '';
            app.RotateImage2 = uibutton(app.UIFigure, 'push');
            app.RotateImage2.ButtonPushedFcn = createCallbackFcn(app, @RotateImage2ButtonPushed, true);
            app.RotateImage2.Icon = 'RotateIcon.png';
            app.RotateImage2.Text = '';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = 'LoadIcon.jpg';
            app.LoadImageButton.Text = 'Load Image';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = 'ResetIcon.png';
            app.ResetButton.Text = '    Reset';
            app.BlackWhiteButton = uibutton(app.UIFigure, 'push');
            app.BlackWhiteButton.ButtonPushedFcn = createCallbackFcn(app, @BlackWhiteButtonPushed, true);
            app.BlackWhiteButton.Icon = 'BWIcon.png';
            app.BlackWhiteButton.Text = 'Black&White';
            app.EdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.EdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectionButtonPushed, true);
            app.EdgeDetectionButton.Text = 'Edge Detection';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [0 4];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [-200 200];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.RedAxes = uiaxes(app.UIFigure);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.GreenAxes = uiaxes(app.UIFigure);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Intensity')
            ylabel(app.GreenAxes, 'Pixels')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.BlueAxes = uiaxes(app.UIFigure);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Intensity')
            ylabel(app.BlueAxes, 'Pixels')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageEditingAppFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end